classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Initial_InterfaceTab            matlab.ui.container.Tab
        ClickonyourdesiredoptionButtonGroup  matlab.ui.container.ButtonGroup
        FindanyValueDontPressButton     matlab.ui.control.ToggleButton
        CalculatedHButton               matlab.ui.control.ToggleButton
        DontlookButton                  matlab.ui.control.ToggleButton
        CalculatedHTab                  matlab.ui.container.Tab
        EnthalpyEditField               matlab.ui.control.NumericEditField
        EnthalpyEditFieldLabel          matlab.ui.control.Label
        DoneButton                      matlab.ui.control.Button
        OutletPhaseDropDown             matlab.ui.control.DropDown
        OutletPhaseDropDownLabel        matlab.ui.control.Label
        InletPhaseDropDown              matlab.ui.control.DropDown
        InletPhaseDropDownLabel         matlab.ui.control.Label
        OutletTempUnitTextArea          matlab.ui.control.TextArea
        OutletTemperatuareEditField     matlab.ui.control.NumericEditField
        OutletTemperatuareEditFieldLabel  matlab.ui.control.Label
        InletTempUnitTextArea           matlab.ui.control.TextArea
        InletTemperatureEditField       matlab.ui.control.NumericEditField
        InletTemperatureEditFieldLabel  matlab.ui.control.Label
        WriteComponentNameDropDown      matlab.ui.control.DropDown
        WriteComponentNameDropDownLabel  matlab.ui.control.Label
        BackButton                      matlab.ui.control.Button
        SearchanyValueTab               matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        OutputEditField                 matlab.ui.control.NumericEditField
        OutputEditFieldLabel            matlab.ui.control.Label
        DoneButton_2                    matlab.ui.control.Button
        PhaseDropDown                   matlab.ui.control.DropDown
        PhaseDropDownLabel              matlab.ui.control.Label
        WriteComponentNameDropDown_2    matlab.ui.control.DropDown
        WriteComponentNameDropDown_2Label  matlab.ui.control.Label
        ChooseanyDropDown               matlab.ui.control.DropDown
        ChooseanyDropDownLabel          matlab.ui.control.Label
        BackButton_2                    matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % assignin("base","componen",app.WriteComponentNameDropDown.Value)
            % assignin("base","Itemp", app.InletTemperatureEditField.Value)
            % assignin("base", "Otemp", app.OutletTemperatuareEditField.Value)
            component=app.WriteComponentNameDropDown.Value;
            i_temp= app.InletTemperatureEditField.Value;
            o_temp= app.OutletTemperatuareEditField.Value;

            table=readtable("data_table.xlsx");
            compotable= table.Component;
            logical_index = strcmp(compotable, component);
            [row, col] = find(logical_index);
            
            atable=table.a;
            btable=table.b;
            ctable=table.c;
            dtable=table.d;
            
            a=atable(row);
            b=btable(row);
            c=ctable(row);
            d=dtable(row);


            P= [d c b a];

            p= polyint(P);
            Cp= polyval(p, o_temp) - polyval(p, i_temp);

            app.EnthalpyEditField.Value=Cp;

            % if i_phase~=o_phase
            %     if i_phase=='l' && o_phase=='g'
            % 
            %     Hv=100;
            %     H= Hi+100;
            % else
            %     H=Hi;
            % end

            
        end

        % Drop down opening function: InletPhaseDropDown
        function InletPhaseDropDownOpening(app, event)
            component=app.WriteComponentNameDropDown.Value;
            table=readtable("data_table.xlsx");
            compotable= table.Component;
            logical_index = strcmp(compotable, component);
            [row, col] = find(logical_index);

            phasetable = table.Phase;
            phase= phasetable(row);

            app.InletPhaseDropDown.Items= phase;
        end

        % Drop down opening function: OutletPhaseDropDown
        function OutletPhaseDropDownOpening(app, event)
            component=app.WriteComponentNameDropDown.Value;
            table=readtable("data_table.xlsx");
            compotable= table.Component;
            logical_index = strcmp(compotable, component);
            [row, col] = find(logical_index);

            phasetable = table.Phase;
            phase= phasetable(row);

            app.OutletPhaseDropDown.Items= phase;
        end

        % Value changed function: WriteComponentNameDropDown
        function TextAreaValueChanged(app, event)
            component=app.WriteComponentNameDropDown.Value;
            table=readtable("data_table.xlsx");
            compotable= table.Component;
            logical_index = strcmp(compotable, component);
            [row, col] = find(logical_index);
            
            TempUnitTable = table.TempUnit;
            TempUnit= TempUnitTable(row);


            app.InletTempUnitTextArea.Value= TempUnit(1);
            app.OutletTempUnitTextArea.Value= TempUnit(1);
        end

        % Selection changed function: ClickonyourdesiredoptionButtonGroup
        function ClickonyourdesiredoptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ClickonyourdesiredoptionButtonGroup.SelectedObject;
            if selectedButton == app.CalculatedHButton
                app.TabGroup.SelectedTab=app.CalculatedHTab;
            elseif selectedButton == app.FindanyValueDontPressButton
                app.TabGroup.SelectedTab=app.SearchanyValueTab;
                pause(5.0)
                app.Image.Visible="on";
                app.TextArea.Visible="on";
            end

        end

        % Button pushed function: BackButton, BackButton_2
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.Initial_InterfaceTab;
            app.DontlookButton.Value=true;
        end

        % Drop down opening function: PhaseDropDown
        function PhaseDropDownOpening(app, event)
            component = app.WriteComponentNameDropDown_2.Value;
            table=readtable("data_table.xlsx");
            compotable= table.Component;
            logical_index = strcmp(compotable, component);
            [row, col] = find(logical_index);

            phasetable = table.Phase;
            phase= phasetable(row);

            app.PhaseDropDown.Items= phase;
        end

        % Drop down opening function: WriteComponentNameDropDown_2
        function WriteComponentNameDropDown_2Opening(app, event)
            table=readtable("data_table.xlsx");
            compotable= table.Component;
           
            app.WriteComponentNameDropDown_2.Items= compotable;

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delta_H = 60000; % from your main thermodynamics calculation
thermo_race_pro(delta_H)

function thermo_race_pro(delta_H)

clc; close all;

% -------------------------------
% Car Data
% -------------------------------
cars = {"Economy","Sedan","SUV","Sports"};
energy_per_km = [1800, 2500, 3500, 5000];
colors = [0 0.7 0; 0 0 1; 0 0 0; 1 0 0];

% -------------------------------
% FIGURE
% -------------------------------
f = figure('Name','🔥 Thermo Racing Pro 🔥',...
    'Position',[200 100 1000 600],'Color',[0.95 0.95 0.95]);

uicontrol(f,'Style','text','String','THERMO RACE PRO',...
    'FontSize',16,'FontWeight','bold',...
    'Position',[350 550 300 30],'BackgroundColor',[0.95 0.95 0.95]);

% -------------------------------
% UI CONTROLS
% -------------------------------
uicontrol(f,'Style','text','String','Choose Car',...
    'Position',[50 480 150 20]);

carMenu = uicontrol(f,'Style','popupmenu',...
    'Position',[50 450 150 30],'String',cars);

uicontrol(f,'Style','text','String','Guess Distance (km)',...
    'Position',[50 400 150 20]);

guessBox = uicontrol(f,'Style','edit',...
    'Position',[50 370 150 30]);

startBtn = uicontrol(f,'Style','pushbutton','String','START RACE',...
    'Position',[50 300 150 50],'Callback',@startRace);

% -------------------------------
% AXES
% -------------------------------
ax = axes(f,'Units','pixels','Position',[250 120 700 350]);
xlim([0 100]); ylim([0 25]);
axis off; hold on;

% Road lanes
for i = 1:4
    y = 5*i;
    plot([0 100],[y y],'k','LineWidth',2);
end

% Finish line
plot([100 100],[0 25],'--k','LineWidth',2);

% -------------------------------
% MULTIPLE CARS
% -------------------------------
carShapes = gobjects(4,1);
for i = 1:4
    y = 5*i;
    carShapes(i) = rectangle('Position',[0 y 5 2],...
        'FaceColor',colors(i,:));
end

% -------------------------------
% FUEL BAR
% -------------------------------
fuelBarBg = rectangle('Position',[50 200 150 20],'FaceColor',[0.8 0.8 0.8]);
fuelBar = rectangle('Position',[50 200 150 20],'FaceColor','g');

uicontrol(f,'Style','text','Position',[50 225 150 20],...
    'String','Energy (Fuel)','BackgroundColor',[0.95 0.95 0.95]);

% -------------------------------
% RESULT TEXT
% -------------------------------
resultText = uicontrol(f,'Style','text',...
    'Position',[50 150 180 80],...
    'String','Result will appear here',...
    'BackgroundColor',[0.95 0.95 0.95]);

% -------------------------------
% ENGINE SOUND FUNCTION
% -------------------------------
function playEngineSound(speedFactor)
    fs = 8000;
    t = 0:1/fs:0.1;
    soundWave = sin(2*pi*(200+speedFactor*300)*t);
    sound(soundWave,fs);
end

% -------------------------------
% RACE FUNCTION
% -------------------------------
function startRace(~,~)

    idx = get(carMenu,'Value');
    guess = str2double(get(guessBox,'String'));
    
    if isnan(guess)
        errordlg('Enter a valid number!');
        return;
    end
    
    actual_distance = delta_H / energy_per_km(idx);
    
    % Scale distances for all cars
    distances = delta_H ./ energy_per_km;
    scaled = min(distances,100);
    
    % Reset positions
    for i = 1:4
        set(carShapes(i),'Position',[0 5*i 5 2]);
    end
    
    % Fuel setup
    totalSteps = max(scaled);
    
    % -------------------------------
    % ANIMATION LOOP
    % -------------------------------
    for step = 0:1:totalSteps
        
        for i = 1:4
            if step <= scaled(i)
                pos = [step 5*i 5 2];
                set(carShapes(i),'Position',pos);
            end
        end
        
        % Update fuel bar
        remaining = max(0,1 - step/totalSteps);
        set(fuelBar,'Position',[50 200 150*remaining 20]);
        
        % Engine sound (speed depends on step)
        playEngineSound(step/totalSteps);
        
        drawnow;
    end
    
    % -------------------------------
    % SCORING
    % -------------------------------
    error = abs(actual_distance - guess);
    
    if actual_distance == 0
        score = 0;
    else
        score = max(0,100 - (error/actual_distance)*100);
    end
    
    % Winner
    [~,winner] = max(distances);

resultStr = sprintf(['🏁 RESULT 🏁\n\n',...
        'Your Car Distance: %.2f km\n',...
        'Winner: %s\n',...
        'Score: %.1f / 100'],...
        actual_distance, cars{winner}, score);
    
    set(resultText,'String',resultStr);
    
end

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Initial_InterfaceTab = uitab(app.TabGroup);
            app.Initial_InterfaceTab.Title = 'Initial_Interface';
            app.ClickonyourdesiredoptionButtonGroup = uibuttongroup(app.Initial_InterfaceTab);
            app.ClickonyourdesiredoptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClickonyourdesiredoptionButtonGroupSelectionChanged, true);
            app.ClickonyourdesiredoptionButtonGroup.Title = 'Click on your desired option';
            app.DontlookButton = uitogglebutton(app.ClickonyourdesiredoptionButtonGroup);
            app.DontlookButton.Visible = 'off';
            app.DontlookButton.Text = 'Don''t look';
            app.DontlookButton.Value = true;
            app.CalculatedHButton = uitogglebutton(app.ClickonyourdesiredoptionButtonGroup);
            app.CalculatedHButton.Text = 'Calculate dH';
            app.FindanyValueDontPressButton = uitogglebutton(app.ClickonyourdesiredoptionButtonGroup);
            app.FindanyValueDontPressButton.Text = 'Find any Value(Don''t Press)';
            app.CalculatedHTab = uitab(app.TabGroup);
            app.CalculatedHTab.Title = 'Calculate dH';
            app.BackButton = uibutton(app.CalculatedHTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '<-- Back';
            app.WriteComponentNameDropDownLabel = uilabel(app.CalculatedHTab);
            app.WriteComponentNameDropDownLabel.Text = 'Write Component Name';
            app.WriteComponentNameDropDown = uidropdown(app.CalculatedHTab);
            app.WriteComponentNameDropDown.Items = {'Component', 'CH4', 'C2H6', 'C3H8', 'C4H10'};
            app.WriteComponentNameDropDown.Editable = 'on';
            app.WriteComponentNameDropDown.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.WriteComponentNameDropDown.Value = 'Component';
            app.InletTemperatureEditFieldLabel = uilabel(app.CalculatedHTab);
            app.InletTemperatureEditFieldLabel.Text = 'Inlet Temperature';
            app.InletTemperatureEditField = uieditfield(app.CalculatedHTab, 'numeric');
            app.InletTempUnitTextArea = uitextarea(app.CalculatedHTab);
            app.InletTempUnitTextArea.Editable = 'off';
            app.OutletTemperatuareEditFieldLabel = uilabel(app.CalculatedHTab);
            app.OutletTemperatuareEditFieldLabel.Text = 'Outlet Temperatuare';
            app.OutletTemperatuareEditField = uieditfield(app.CalculatedHTab, 'numeric');
            app.OutletTempUnitTextArea = uitextarea(app.CalculatedHTab);
            app.OutletTempUnitTextArea.Editable = 'off';
            app.InletPhaseDropDownLabel = uilabel(app.CalculatedHTab);
            app.InletPhaseDropDownLabel.Text = 'Inlet Phase';
            app.InletPhaseDropDown = uidropdown(app.CalculatedHTab);
            app.InletPhaseDropDown.Items = {'s', 'l', 'g'};
            app.InletPhaseDropDown.DropDownOpeningFcn = createCallbackFcn(app, @InletPhaseDropDownOpening, true);
            app.InletPhaseDropDown.Value = 's';
            app.OutletPhaseDropDownLabel = uilabel(app.CalculatedHTab);
            app.OutletPhaseDropDownLabel.Text = 'Outlet Phase';
            app.OutletPhaseDropDown = uidropdown(app.CalculatedHTab);
            app.OutletPhaseDropDown.Items = {'s', 'l', 'g'};
            app.OutletPhaseDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OutletPhaseDropDownOpening, true);
            app.OutletPhaseDropDown.Value = 's';
            app.DoneButton = uibutton(app.CalculatedHTab, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.EnthalpyEditFieldLabel = uilabel(app.CalculatedHTab);
            app.EnthalpyEditFieldLabel.Text = 'Enthalpy';
            app.EnthalpyEditField = uieditfield(app.CalculatedHTab, 'numeric');
            app.EnthalpyEditField.Editable = 'off';
            app.SearchanyValueTab = uitab(app.TabGroup);
            app.SearchanyValueTab.Title = 'Search any Value';
            app.BackButton_2 = uibutton(app.SearchanyValueTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_2.Text = '<-- Back';
            app.ChooseanyDropDownLabel = uilabel(app.SearchanyValueTab);
            app.ChooseanyDropDownLabel.Text = 'Choose any';
            app.ChooseanyDropDown = uidropdown(app.SearchanyValueTab);
            app.ChooseanyDropDown.Items = {'Melting Temperature', 'Boiling Temperature', 'Heat of Fusion', 'Heat of Vaporization'};
            app.ChooseanyDropDown.Value = 'Melting Temperature';
            app.WriteComponentNameDropDown_2Label = uilabel(app.SearchanyValueTab);
            app.WriteComponentNameDropDown_2Label.Visible = 'off';
            app.WriteComponentNameDropDown_2Label.Text = 'Write Component Name';
            app.WriteComponentNameDropDown_2 = uidropdown(app.SearchanyValueTab);
            app.WriteComponentNameDropDown_2.Items = {'Component', 'CH4', 'C2H6', 'C3H8', 'C4H10'};
            app.WriteComponentNameDropDown_2.Editable = 'on';
            app.WriteComponentNameDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @WriteComponentNameDropDown_2Opening, true);
            app.WriteComponentNameDropDown_2.Visible = 'off';
            app.WriteComponentNameDropDown_2.Value = 'Component';
            app.PhaseDropDownLabel = uilabel(app.SearchanyValueTab);
            app.PhaseDropDownLabel.Visible = 'off';
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.SearchanyValueTab);
            app.PhaseDropDown.Items = {'s', 'l', 'g'};
            app.PhaseDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PhaseDropDownOpening, true);
            app.PhaseDropDown.Visible = 'off';
            app.PhaseDropDown.Value = 's';
            app.DoneButton_2 = uibutton(app.SearchanyValueTab, 'push');
            app.DoneButton_2.Text = 'Done';
            app.OutputEditFieldLabel = uilabel(app.SearchanyValueTab);
            app.OutputEditFieldLabel.Visible = 'off';
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.SearchanyValueTab, 'numeric');
            app.OutputEditField.Visible = 'off';
            app.Image = uiimage(app.SearchanyValueTab);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download.jpg');
            app.TextArea = uitextarea(app.SearchanyValueTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'Ki Shomossha! Je Kotha Boli Kane jayna?'};
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end